classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutoOptv01UIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        ResultsLabel                    matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        OpenButton                      matlab.ui.control.Button
        MoreResultsDropDown             matlab.ui.control.DropDown
        MoreResultsLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        GridLayout1                     matlab.ui.container.GridLayout
        ThresEditField2                 matlab.ui.control.NumericEditField
        ThresEditField_3Label           matlab.ui.control.Label
        TmaxEditField2                  matlab.ui.control.NumericEditField
        TmaxEditField_3Label            matlab.ui.control.Label
        IncRateEditField2               matlab.ui.control.NumericEditField
        IncRateEditField_3Label_3       matlab.ui.control.Label
        AlgRunsEditField2               matlab.ui.control.NumericEditField
        AlgRunsEditField_3Label         matlab.ui.control.Label
        ProbFEEditField2                matlab.ui.control.NumericEditField
        ProbFEEditField_3Label          matlab.ui.control.Label
        ProbNEditField2                 matlab.ui.control.NumericEditField
        ProbNEditField_3Label           matlab.ui.control.Label
        ThresEditField                  matlab.ui.control.NumericEditField
        ThresEditField_2Label           matlab.ui.control.Label
        LSRangeEditField                matlab.ui.control.NumericEditField
        IncRateEditField_3Label_2       matlab.ui.control.Label
        IncRateEditField                matlab.ui.control.NumericEditField
        IncRateEditField_2Label_2       matlab.ui.control.Label
        TmaxEditField                   matlab.ui.control.NumericEditField
        TmaxEditField_2Label            matlab.ui.control.Label
        InnerFEEditField                matlab.ui.control.NumericEditField
        InnerFEEditField_2Label_2       matlab.ui.control.Label
        ProbFEEditField                 matlab.ui.control.NumericEditField
        ProbFEEditField_2Label          matlab.ui.control.Label
        ProbNEditField                  matlab.ui.control.NumericEditField
        ProbNEditField_2Label           matlab.ui.control.Label
        AlgRunsEditField                matlab.ui.control.NumericEditField
        AlgRunsEditField_2Label         matlab.ui.control.Label
        AlgFEEditField                  matlab.ui.control.NumericEditField
        AlgFEEditField_2Label           matlab.ui.control.Label
        AlgNEditField                   matlab.ui.control.NumericEditField
        AlgNEditField_2Label_2          matlab.ui.control.Label
        AlgQEditField                   matlab.ui.control.NumericEditField
        AlgQEditField_2Label_2          matlab.ui.control.Label
        AlgPEditField                   matlab.ui.control.NumericEditField
        AlgPEditField_2Label            matlab.ui.control.Label
        ProblemFileEditField            matlab.ui.control.EditField
        ProblemFileEditField_9Label_2   matlab.ui.control.Label
        Button2                         matlab.ui.control.Button
        TrainingInstanceEditField       matlab.ui.control.EditField
        TrainingInstanceEditFieldLabel  matlab.ui.control.Label
        RUNButton2                      matlab.ui.control.Button
        MetricDropDown2                 matlab.ui.control.DropDown
        MetricDropDown_4Label           matlab.ui.control.Label
        SpecifyAlgorithmDropDown        matlab.ui.control.DropDown
        SpecifyAlgorithmLabel           matlab.ui.control.Label
        Button3                         matlab.ui.control.Button
        AlgorithmFileEditField          matlab.ui.control.EditField
        AlgorithmFileEditFieldLabel     matlab.ui.control.Label
        SetParameters2                  matlab.ui.control.Label
        InstanceEditField               matlab.ui.control.EditField
        InstanceEditFieldLabel          matlab.ui.control.Label
        ProblemFileEditField2           matlab.ui.control.EditField
        ProblemFileEditField_9Label     matlab.ui.control.Label
        InputProblem2                   matlab.ui.control.Label
        Solve                           matlab.ui.control.Label
        SetParameters                   matlab.ui.control.Label
        InputProblem                    matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        EvaluateDropDown                matlab.ui.control.DropDown
        EvaluateDropDown_2Label         matlab.ui.control.Label
        CompareDropDown                 matlab.ui.control.DropDown
        CompareDropDown_2Label          matlab.ui.control.Label
        MetricDropDown                  matlab.ui.control.DropDown
        MetricDropDown_3Label           matlab.ui.control.Label
        TuneParaDropDown                matlab.ui.control.DropDown
        TuneParaDropDown_2Label         matlab.ui.control.Label
        TestInstanceEditField           matlab.ui.control.EditField
        TestInstanceEditField_2Label    matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Design                          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AutoOptv01UIFigure.Name = 'AutoOpt v1.0';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            prob = uigetfile('*.m');
            app.ProblemFileEditField.Value = prob;
        end

        % Value changed function: TrainingInstanceEditField
        function TrainingInstanceEditFieldValueChanged(app, event)
            instance = app.TrainingInstanceEditField.Value;
            if contains(instance,',')
                indComma = strfind(instance,',');
                tempInstance = zeros(1,length(indComma)+1);
                tempInstance(1) = str2double(instance(1:indComma(1)-1));
                for i = 1:length(indComma)-1
                    tempInstance(i+1) = str2double(instance(indComma(i)+1:indComma(i+1)-1));
                end
                tempInstance(end) = str2double(instance(indComma(end)+1:end));
                instance = tempInstance;
            elseif contains(instance,'-')
                indLine = strfind(instance,'-');
                instance = str2double(instance(1:indLine-1)):str2double(instance(indLine+1:end));
            elseif length(instance) == 1
                instance = str2double(instance);
            else
                app.TextArea.Value = 'Error: improper format of instance indexes.';
                error('Improper format of instance indexes.');
            end
            setappdata(app.TrainingInstanceEditField,'instance',instance);
        end

        % Value changed function: TestInstanceEditField
        function TestInstanceEditFieldValueChanged(app, event)
            instance = app.TestInstanceEditField.Value;
            if contains(instance,',')
                indComma = strfind(instance,',');
                tempInstance = zeros(1,length(indComma)+1);
                tempInstance(1) = str2double(instance(1:indComma(1)-1));
                for i = 1:length(indComma)-1
                    tempInstance(i+1) = str2double(instance(indComma(i)+1:indComma(i+1)-1));
                end
                tempInstance(end) = str2double(instance(indComma(end)+1:end));
                instance = tempInstance;
            elseif contains(instance,'-')
                indLine = strfind(instance,'-');
                instance = str2double(instance(1:indLine-1)):str2double(instance(indLine+1:end));
            elseif length(instance) == 1
                instance = str2double(instance);
            else
                app.TextArea.Value = 'Error: improper format of instance indexes';
                error('Improper format of instance indexes.');
            end
            setappdata(app.TestInstanceEditField,'instance',instance);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % get problem file and instance indexes
            prob = app.ProblemFileEditField.Value;
            if isempty(prob)
                app.TextArea.Value = 'Error: please load problem file';
                error('Please load problem file.')
            end

            instanceTrain = getappdata(app.TrainingInstanceEditField,'instance');
            if isempty(instanceTrain)
                app.TextArea.Value = 'Error: please set correct format of training instance indexes';
                error('Please set correct format of training instance indexes.')
            end

            instanceTest = getappdata(app.TestInstanceEditField,'instance');
            if isempty(instanceTest)
                app.TextArea.Value = 'Error: please set correct format of text instance indexes';
                error('Please set correct format of text instance indexes.')
            end

            % get parameters
            Setting = struct;
            Setting.AlgP     = app.AlgPEditField.Value;
            Setting.AlgQ     = app.AlgQEditField.Value;
            Setting.AlgN     = app.AlgNEditField.Value;
            Setting.AlgFE    = app.AlgFEEditField.Value;
            Setting.AlgRuns  = app.AlgRunsEditField.Value;
            Setting.LSRange  = app.LSRangeEditField.Value;

            Setting.ProbN    = app.ProbNEditField.Value;
            Setting.ProbFE   = app.ProbFEEditField.Value;
            Setting.InnerFE  = app.InnerFEEditField.Value;
            Setting.IncRate  = app.IncRateEditField.Value;
            Setting.Tmax     = app.TmaxEditField.Value;
            Setting.Thres    = app.ThresEditField.Value;

            Setting.Metric   = app.MetricDropDown.Value;
            Setting.Compare  = app.CompareDropDown.Value;
            Setting.Evaluate = app.EvaluateDropDown.Value;
            Setting.TunePara = app.TuneParaDropDown.Value;
            if strcmp(Setting.TunePara,'false')
                Setting.TunePara = false;
            else
                Setting.TunePara = true;
            end

            Setting.Mode     = 'design';
            Setting.Archive  = '';
            Setting.RacingK  = max(1,round(length(instanceTrain)*0.2));
            Setting.Surro    = Setting.ProbFE*0.2;

            % avoid conflicting parameter settings
            % AlgP or AlgQ should be equal to 1
            if Setting.AlgP > 1 && Setting.AlgQ > 1
                app.TextArea.Value = ['Error: Setting.AlgP or Setting.AlgQ should be equal to 1 - ' ...
                    'For algorithms with multiple search pathways (AlgP>1), ' ...
                    'each search pathway should have only one search operator (AlgQ=1). ' ...
                    'For algorithms with a single search pathway (AlgP=1), ' ...
                    'the search pathway can have multiple search operators (AlgQ>=1).'];
                error(['Setting.AlgP or Setting.AlgQ should be equal to 1 - ' ...
                    'For algorithms with multiple search pathways (AlgP>1), ' ...
                    'each search pathway should have only one search operator (AlgQ=1). ' ...
                    'For algorithms with a single search pathway (AlgP=1), ' ...
                    'the search pathway can have multiple search operators (AlgQ>=1).']);
            end
            % set Tmax and Thres when using runtimeFE or runtimeSec as the performance metric
            if strcmp(Setting.Metric,'runtimeFE') && isempty(Setting.Tmax)
                Setting.Tmax = Setting.ProbFE;
            end
            if strcmp(Setting.Metric,'runtimeFE') && isempty(Setting.Thres)
                app.TextArea.Value = ['Error: please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.'];
                error(['Please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.']);
            end
            if strcmp(Setting.Metric,'runtimeSec') && isempty(Setting.Tmax)
                app.TextArea.Value = 'Error: please set "Setting.Tmax" as the maximum runtime (seconds).';
                error('Please set "Setting.Tmax" as the maximum runtime (seconds).');
            end
            if strcmp(Setting.Metric,'runtimeSec') && isempty(Setting.Thres)
                app.TextArea.Value = ['Error: please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.'];
                error(['Please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.']);
            end
            %  time (Tmax) should be function evaluations when using auc
            if strcmp(Setting.Metric,'auc') && numel(Setting.Tmax) <= 1
                app.TextArea.Value = ['Error: "Setting.Tmax" should contain multiple time points. The time ' ...
                    'points should the numbers of function evaluations spent during ' ...
                    'the alorithm execution.'];
                error(['"Setting.Tmax" should contain multiple time points. The time ' ...
                    'points should the numbers of function evaluations spent during ' ...
                    'the alorithm execution.']);
            end
            % thresholds should be corresponding to time points when using auc
            if strcmp(Setting.Metric,'auc') && numel(Setting.Thres) ~= numel(Setting.Tmax)
                app.TextArea.Value = ['Error: the number of thresholds in "Setting.Thres" should be equal to ' ...
                    'the number of time points in "Setting.Tmax". "Setting.Thres" ' ...
                    'refers to the lowest acceptable performance of the design' ...
                    ' algorithms, the performance can be the solution quality.'];
                error(['The number of thresholds in "Setting.Thres" should be equal to ' ...
                    'the number of time points in "Setting.Tmax". "Setting.Thres" ' ...
                    'refers to the lowest acceptable performance of the design' ...
                    ' algorithms, the performance can be the solution quality.']);
            end
            % the "racing" evaluation method should be used with the "statictic" algorithm comparing method
            if strcmp(Setting.Evaluate,'racing') && ~strcmp(Setting.Compare,'statistic')
                app.TextArea.Value = ['Error: the "racing" evaluation method should be used with the ' ...
                    'algorithm comparing method of "statistic". '];
                error(['The "racing" evaluation method should be used with the ' ...
                    'algorithm comparing method of "statistic". ']);
            end
            % should set K when using the "racing" evaluation method
            if strcmp(Setting.Evaluate,'racing') && isempty(Setting.RacingK)
                app.TextArea.Value = cellstr(['Error: please set "Setting.K" as the number of instances evaluated ' ...
                    'before the first round of racing.']);
                error(['Please set "Setting.K" as the number of instances evaluated ' ...
                    'before the first round of racing.'])
            end
            % it is not necessary to use "statistic" algorithm comparing method when using the "approximate" evaluation method
            if strcmp(Setting.Evaluate,'approximate') && strcmp(Setting.Compare,'statistic')
                app.TextArea.Value = ['Error: it is not necessary to use the "statistic" algorithm ' ...
                    'comparing method when using the "approximate" evaluation method.'];
                error(['It is not necessary to use the "statistic" algorithm ' ...
                    'comparing method when using the "approximate" evaluation method.']);
            end
            % should run the design multiple times when using the "statistic" comparsion method
            if strcmp(Setting.Compare,'statistic') && Setting.AlgRuns == 1
                app.TextArea.Value = ['Error: please run the design multiple times (Setting.AlgRuns>1)' ...
                    ' when using the "statistic" comparsion method.'];
                error(['Please run the design multiple times (Setting.AlgRuns>1)' ...
                    ' when using the "statistic" comparsion method.']);
            end
            % better to have a large population size when involving the EDA operator
            if Setting.ProbN < 5 && Setting.AlgP > 1
                app.TextArea.Value = ['Warning: it is better to have a large population size if ' ...
                    'involving the EDA operator'];
                warning(['It is better to have a large population size if ' ...
                    'involving the EDA operator']);
            end
            %
            if Setting.TunePara == true
                app.TextArea.Value = ['Warning: now is turning the parameters of the algorithm ' ...
                    'specified in Space.m.'];
                warning(['Now is turning the parameters of the algorithm ' ...
                    'specified in Space.m.']);
            end

            % run
            cla(app.UIAxes);
            app.UITable.Data = [];
            [algs,performance,performTrend] = Process(prob,instanceTrain,instanceTest,app,Setting);
            Output(algs,performance,performTrend,instanceTest,Setting);

            % display results         
            x = 1:length(performTrend);
            y = performTrend;
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes,'Iteration of the Design Process');
            ylabel(app.UIAxes,'Algorithm Performance');
            title(app.UIAxes,'Convergence Curve of the Design Process')

            bestAlg = readcell('Algs.xlsx','sheet','Algorithm 1');
            for i = 1:size(bestAlg,1)
                for j = 1:size(bestAlg,2)
                    if ismissing(bestAlg{i,j})
                        bestAlg{i,j} = '';
                    end
                end
            end
            firstRow = cell(1,size(bestAlg,2));
            firstRow{1} = 'Best algorithm:';
            app.UITable.Data = [firstRow;bestAlg];
            app.TextArea.Value = 'Design complete.';
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            prob = uigetfile('*.m');
            app.ProblemFileEditField2.Value = prob;
        end

        % Value changed function: InstanceEditField
        function InstanceEditFieldValueChanged(app, event)
            instance = app.InstanceEditField.Value;
            if contains(instance,',')
                indComma = strfind(instance,',');
                tempInstance = zeros(1,length(indComma)+1);
                tempInstance(1) = str2double(instance(1:indComma(1)-1));
                for i = 1:length(indComma)-1
                    tempInstance(i+1) = str2double(instance(indComma(i)+1:indComma(i+1)-1));
                end
                tempInstance(end) = str2double(instance(indComma(end)+1:end));
                instance = tempInstance;
            elseif contains(instance,'-')
                indLine = strfind(instance,'-');
                instance = str2double(instance(1:indLine-1)):str2double(instance(indLine+1:end));
            elseif length(instance) == 1
                instance = str2double(instance);
            else
                app.TextArea.Value = 'Error: improper format of instance indexes.';
                error('Improper format of instance indexes.');
            end
            setappdata(app.InstanceEditField,'instance',instance);
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            alg = uigetfile('*.mat');
            app.AlgorithmFileEditField.Value = alg;
        end

        % Button pushed function: RUNButton2
        function RUNButton2Pushed(app, event)
            % get problem file and instance indexes
            prob = app.ProblemFileEditField2.Value;
            if isempty(prob)
                app.TextArea.Value = 'Error: please load problem file.';
                error('Please load problem file.')
            end

            instanceSolve = getappdata(app.InstanceEditField,'instance');
            if isempty(instanceSolve)
                app.TextArea.Value = 'Error: please set correct format of instance indexes.';
                error('Please set correct format of instance indexes.')
            end

            % get parameters
            Setting = struct;
            Setting.Mode    = 'solve';
            Setting.AlgFile = app.AlgorithmFileEditField.Value;
            Setting.AlgName = app.SpecifyAlgorithmDropDown.Value;
            Setting.Metric  = app.MetricDropDown2.Value;
            Setting.ProbN   = app.ProbNEditField2.Value;
            Setting.ProbFE  = app.ProbFEEditField2.Value;
            Setting.AlgRuns = app.AlgRunsEditField2.Value;
            Setting.IncRate = app.IncRateEditField2.Value;
            Setting.Tmax    = app.TmaxEditField2.Value;
            Setting.Thres   = app.ThresEditField2.Value;

            % avoid conflicting parameter settings
            % should specify the file or name of the algorithm being used
            if isempty(Setting.AlgFile) && isempty(Setting.AlgName)
                app.TextArea.Value = ['Error: please specify an algorithm file in Setting.AlgFile or ' ...
                    'specify an algorithm name in Setting.AlgName.'];
                error(['Please specify an algorithm file in Setting.AlgFile or ' ...
                    'specify an algorithm name in Setting.AlgName.'])
            end
            % set Tmax and Thres when using runtimeFE or runtimeSec as the performance metric
            if strcmp(Setting.Metric,'runtimeFE') && isempty(Setting.Tmax)
                Setting.Tmax = Setting.ProbFE;
            end
            if strcmp(Setting.Metric,'runtimeFE') && isempty(Setting.Thres)
                app.TextArea.Value = ['Error: please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.'];
                error(['Please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.'])
            end
            if strcmp(Setting.Metric,'runtimeSec') && isempty(Setting.Tmax)
                app.TextArea.Value = 'Error: please set "Setting.Tmax" as the maximum runtime (seconds).';
                error('Please set "Setting.Tmax" as the maximum runtime (seconds).')
            end
            if strcmp(Setting.Metric,'runtimeSec') && isempty(Setting.Thres)
                app.TextArea.Value = ['Error: please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.'];
                error(['Please set "Setting.Thres" as the lowest acceptable performance ' ...
                    'of the design algorithms, the performance can be the solution quality.'])
            end
            %  time (Tmax) should be function evaluations when using auc
            if strcmp(Setting.Metric,'auc') && numel(Setting.Tmax) <= 1
                app.TextArea.Value = ['Error: "Setting.Tmax" should contain multiple time points. The time ' ...
                    'points should the numbers of function evaluations spent during ' ...
                    'the alorithm execution.'];
                error(['"Setting.Tmax" should contain multiple time points. The time ' ...
                    'points should the numbers of function evaluations spent during ' ...
                    'the alorithm execution.'])
            end
            % thresholds should be corresponding to time points when using auc
            if strcmp(Setting.Metric,'auc') && numel(Setting.Thres) ~= numel(Setting.Tmax)
                app.TextArea.Value = ['Error: the number of thresholds in "Setting.Thres" should be equal to ' ...
                    'the number of time points in "Setting.Tmax". "Setting.Thres" ' ...
                    'refers to the lowest acceptable performance of the design' ...
                    ' algorithms, the performance can be the solution quality.'];
                error(['The number of thresholds in "Setting.Thres" should be equal to ' ...
                    'the number of time points in "Setting.Tmax". "Setting.Thres" ' ...
                    'refers to the lowest acceptable performance of the design' ...
                    ' algorithms, the performance can be the solution quality.'])
            end

            % run
            cla(app.UIAxes);
            app.UITable.Data = [];
            [bestSolutions,allSolutions,~] = Process(prob,instanceSolve,app,Setting);
            Output(bestSolutions,allSolutions,instanceSolve,Setting);

            % display results
            results = readcell('Solutions.xlsx','sheet','Fitness'); % fitness on all instances
            results(:,1:2) = [];
            results(1,:)   = [];
            results = cellfun(@str2double,results);
            results = mean(results,1); % average fitness over all instances
            plot(app.UIAxes,1:length(results),results);
            xlabel(app.UIAxes,'Iteration of the Solving Process');
            ylabel(app.UIAxes,'Solution Fitness');
            title(app.UIAxes,'Convergence Curve of the Solving Process')

            bestSolution = readcell('Solutions.xlsx','sheet','Solutions','Range','A:B');
            app.UITable.Data = bestSolution;
            app.TextArea.Value = 'Solve complete.';        
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            filename = app.MoreResultsDropDown.Value;
            if strcmp(filename,'Algorithm Profile') 
                open('Algs.xlsx');
            elseif strcmp(filename,'Solutions')
                open('Solutions.xlsx');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AutoOptv01UIFigure = uifigure('Visible', 'off');
            app.AutoOptv01UIFigure.Name = 'AutoOpt v0.1';
            app.GridLayout = uigridlayout(app.AutoOptv01UIFigure);
            app.GridLayout1 = uigridlayout(app.GridLayout);
            app.Design = uilabel(app.GridLayout1);
            app.Design.Text = 'Design';
            app.Button = uibutton(app.GridLayout1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Open.jpeg');
            app.Button.Text = '...';
            app.TestInstanceEditField_2Label = uilabel(app.GridLayout1);
            app.TestInstanceEditField_2Label.Text = ' Test Instance';
            app.TestInstanceEditField = uieditfield(app.GridLayout1, 'text');
            app.TestInstanceEditField.ValueChangedFcn = createCallbackFcn(app, @TestInstanceEditFieldValueChanged, true);
            app.TuneParaDropDown_2Label = uilabel(app.GridLayout1);
            app.TuneParaDropDown_2Label.Text = 'TunePara';
            app.TuneParaDropDown = uidropdown(app.GridLayout1);
            app.TuneParaDropDown.Items = {'false', 'true'};
            app.TuneParaDropDown.Value = 'false';
            app.MetricDropDown_3Label = uilabel(app.GridLayout1);
            app.MetricDropDown_3Label.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.GridLayout1);
            app.MetricDropDown.Items = {'quality', 'runtimeFE', 'runtimeSec', 'AUC'};
            app.MetricDropDown.Value = 'quality';
            app.CompareDropDown_2Label = uilabel(app.GridLayout1);
            app.CompareDropDown_2Label.Text = 'Compare';
            app.CompareDropDown = uidropdown(app.GridLayout1);
            app.CompareDropDown.Items = {'average', 'statistic'};
            app.CompareDropDown.Value = 'average';
            app.EvaluateDropDown_2Label = uilabel(app.GridLayout1);
            app.EvaluateDropDown_2Label.Text = 'Evaluate';
            app.EvaluateDropDown = uidropdown(app.GridLayout1);
            app.EvaluateDropDown.Items = {'default', 'approximate', 'intensification', 'racing'};
            app.EvaluateDropDown.Value = 'default';
            app.RUNButton = uibutton(app.GridLayout1, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.InputProblem = uilabel(app.GridLayout1);
            app.InputProblem.Text = 'Input Problem';
            app.SetParameters = uilabel(app.GridLayout1);
            app.SetParameters.Text = 'Set Parameters';
            app.Solve = uilabel(app.GridLayout1);
            app.Solve.Text = 'Solve';
            app.InputProblem2 = uilabel(app.GridLayout1);
            app.InputProblem2.Text = 'Input Problem';
            app.ProblemFileEditField_9Label = uilabel(app.GridLayout1);
            app.ProblemFileEditField_9Label.Text = 'Problem File';
            app.ProblemFileEditField2 = uieditfield(app.GridLayout1, 'text');
            app.InstanceEditFieldLabel = uilabel(app.GridLayout1);
            app.InstanceEditFieldLabel.Text = 'Instance';
            app.InstanceEditField = uieditfield(app.GridLayout1, 'text');
            app.InstanceEditField.ValueChangedFcn = createCallbackFcn(app, @InstanceEditFieldValueChanged, true);
            app.SetParameters2 = uilabel(app.GridLayout1);
            app.SetParameters2.Text = 'Set Parameters';
            app.AlgorithmFileEditFieldLabel = uilabel(app.GridLayout1);
            app.AlgorithmFileEditFieldLabel.Text = 'Algorithm File';
            app.AlgorithmFileEditField = uieditfield(app.GridLayout1, 'text');
            app.Button3 = uibutton(app.GridLayout1, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Icon = fullfile(pathToMLAPP, 'Open.jpeg');
            app.Button3.Text = '...';
            app.SpecifyAlgorithmLabel = uilabel(app.GridLayout1);
            app.SpecifyAlgorithmLabel.Text = 'Specify Algorithm';
            app.SpecifyAlgorithmDropDown = uidropdown(app.GridLayout1);
            app.SpecifyAlgorithmDropDown.Items = {'None', 'Continuous Genetic Algorithm', 'Evolutionary Programming', 'Fast Evolutionary Programming', 'CMA-ES', 'Estimation of Distribution', 'Particle Swarm Optimization', 'Differential Evolution', 'Discrete Genetic Algorithm', 'Discrete Iterative Local Search', 'Permutation Genetic Algorithm', 'Permutation Iterative Local Search', 'Permutation Variable Neighborhood Search'};
            app.SpecifyAlgorithmDropDown.Value = 'None';
            app.MetricDropDown_4Label = uilabel(app.GridLayout1);
            app.MetricDropDown_4Label.Text = 'Metric';
            app.MetricDropDown2 = uidropdown(app.GridLayout1);
            app.MetricDropDown2.Items = {'quality', 'runtimeFE', 'runtimeSec', 'auc'};
            app.MetricDropDown2.Value = 'quality';
            app.RUNButton2 = uibutton(app.GridLayout1, 'push');
            app.RUNButton2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton2Pushed, true);
            app.RUNButton2.Text = 'RUN';
            app.TrainingInstanceEditFieldLabel = uilabel(app.GridLayout1);
            app.TrainingInstanceEditFieldLabel.Text = ' Training Instance';
            app.TrainingInstanceEditField = uieditfield(app.GridLayout1, 'text');
            app.TrainingInstanceEditField.ValueChangedFcn = createCallbackFcn(app, @TrainingInstanceEditFieldValueChanged, true);
            app.Button2 = uibutton(app.GridLayout1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = fullfile(pathToMLAPP, 'Open.jpeg');
            app.Button2.Text = '...';
            app.ProblemFileEditField_9Label_2 = uilabel(app.GridLayout1);
            app.ProblemFileEditField_9Label_2.Text = 'Problem File';
            app.ProblemFileEditField = uieditfield(app.GridLayout1, 'text');
            app.AlgPEditField_2Label = uilabel(app.GridLayout1);
            app.AlgPEditField_2Label.Text = 'AlgP';
            app.AlgPEditField = uieditfield(app.GridLayout1, 'numeric');
            app.AlgPEditField.ValueDisplayFormat = '%.0f';
            app.AlgPEditField.Value = 1;
            app.AlgQEditField_2Label_2 = uilabel(app.GridLayout1);
            app.AlgQEditField_2Label_2.Text = 'AlgQ';
            app.AlgQEditField = uieditfield(app.GridLayout1, 'numeric');
            app.AlgQEditField.ValueDisplayFormat = '%.0f';
            app.AlgQEditField.Value = 2;
            app.AlgNEditField_2Label_2 = uilabel(app.GridLayout1);
            app.AlgNEditField_2Label_2.Text = 'AlgN';
            app.AlgNEditField = uieditfield(app.GridLayout1, 'numeric');
            app.AlgNEditField.ValueDisplayFormat = '%.0f';
            app.AlgNEditField.Value = 10;
            app.AlgFEEditField_2Label = uilabel(app.GridLayout1);
            app.AlgFEEditField_2Label.Text = 'AlgFE';
            app.AlgFEEditField = uieditfield(app.GridLayout1, 'numeric');
            app.AlgFEEditField.ValueDisplayFormat = '%.0f';
            app.AlgFEEditField.Value = 5000;
            app.AlgRunsEditField_2Label = uilabel(app.GridLayout1);
            app.AlgRunsEditField_2Label.Text = 'AlgRuns';
            app.AlgRunsEditField = uieditfield(app.GridLayout1, 'numeric');
            app.AlgRunsEditField.ValueDisplayFormat = '%.0f';
            app.AlgRunsEditField.Value = 20;
            app.ProbNEditField_2Label = uilabel(app.GridLayout1);
            app.ProbNEditField_2Label.Text = 'ProbN';
            app.ProbNEditField = uieditfield(app.GridLayout1, 'numeric');
            app.ProbNEditField.ValueDisplayFormat = '%.0f';
            app.ProbNEditField.Value = 50;
            app.ProbFEEditField_2Label = uilabel(app.GridLayout1);
            app.ProbFEEditField_2Label.Text = 'ProbFE';
            app.ProbFEEditField = uieditfield(app.GridLayout1, 'numeric');
            app.ProbFEEditField.ValueDisplayFormat = '%.0f';
            app.ProbFEEditField.Value = 5000;
            app.InnerFEEditField_2Label_2 = uilabel(app.GridLayout1);
            app.InnerFEEditField_2Label_2.Text = 'InnerFE';
            app.InnerFEEditField = uieditfield(app.GridLayout1, 'numeric');
            app.InnerFEEditField.ValueDisplayFormat = '%.0f';
            app.InnerFEEditField.Value = 500;
            app.TmaxEditField_2Label = uilabel(app.GridLayout1);
            app.TmaxEditField_2Label.Text = 'Tmax';
            app.TmaxEditField = uieditfield(app.GridLayout1, 'numeric');
            app.TmaxEditField.Value = Inf;
            app.IncRateEditField_2Label_2 = uilabel(app.GridLayout1);
            app.IncRateEditField_2Label_2.Text = 'IncRate';
            app.IncRateEditField = uieditfield(app.GridLayout1, 'numeric');
            app.IncRateEditField.Value = 0.05;
            app.IncRateEditField_3Label_2 = uilabel(app.GridLayout1);
            app.IncRateEditField_3Label_2.Text = 'LSRange';
            app.LSRangeEditField = uieditfield(app.GridLayout1, 'numeric');
            app.LSRangeEditField.Value = 0.2;
            app.ThresEditField_2Label = uilabel(app.GridLayout1);
            app.ThresEditField_2Label.Text = 'Thres';
            app.ThresEditField = uieditfield(app.GridLayout1, 'numeric');
            app.ThresEditField.Value = Inf;
            app.ProbNEditField_3Label = uilabel(app.GridLayout1);
            app.ProbNEditField_3Label.Text = 'ProbN';
            app.ProbNEditField2 = uieditfield(app.GridLayout1, 'numeric');
            app.ProbNEditField2.ValueDisplayFormat = '%.0f';
            app.ProbNEditField2.Value = 100;
            app.ProbFEEditField_3Label = uilabel(app.GridLayout1);
            app.ProbFEEditField_3Label.Text = 'ProbFE';
            app.ProbFEEditField2 = uieditfield(app.GridLayout1, 'numeric');
            app.ProbFEEditField2.ValueDisplayFormat = '%.0f';
            app.ProbFEEditField2.Value = 50000;
            app.AlgRunsEditField_3Label = uilabel(app.GridLayout1);
            app.AlgRunsEditField_3Label.Text = 'AlgRuns';
            app.AlgRunsEditField2 = uieditfield(app.GridLayout1, 'numeric');
            app.AlgRunsEditField2.ValueDisplayFormat = '%.0f';
            app.AlgRunsEditField2.Value = 30;
            app.IncRateEditField_3Label_3 = uilabel(app.GridLayout1);
            app.IncRateEditField_3Label_3.Text = 'IncRate';
            app.IncRateEditField2 = uieditfield(app.GridLayout1, 'numeric');
            app.IncRateEditField2.Value = 0.05;
            app.TmaxEditField_3Label = uilabel(app.GridLayout1);
            app.TmaxEditField_3Label.Text = 'Tmax';
            app.TmaxEditField2 = uieditfield(app.GridLayout1, 'numeric');
            app.TmaxEditField2.Value = Inf;
            app.ThresEditField_3Label = uilabel(app.GridLayout1);
            app.ThresEditField_3Label.Text = 'Thres';
            app.ThresEditField2 = uieditfield(app.GridLayout1, 'numeric');
            app.ThresEditField2.Value = Inf;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.MoreResultsLabel = uilabel(app.GridLayout2);
            app.MoreResultsLabel.Text = 'More Results';
            app.MoreResultsDropDown = uidropdown(app.GridLayout2);
            app.MoreResultsDropDown.Items = {'Algorithm Profile', 'Solutions'};
            app.MoreResultsDropDown.Value = 'Algorithm Profile';
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.TextArea = uitextarea(app.GridLayout2);
            app.ResultsLabel = uilabel(app.GridLayout2);
            app.ResultsLabel.Text = 'Results';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.AutoOptv01UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutoOptv01UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutoOptv01UIFigure)
        end
    end
end